classdef GraficasCalidad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        IniciarmovimientoButton  matlab.ui.control.Button
        GridLayout3              matlab.ui.container.GridLayout
        ResultadosButton         matlab.ui.control.Button
        FHombro                  matlab.ui.control.UIAxes
        AHombro                  matlab.ui.control.UIAxes
        SCodo                    matlab.ui.control.UIAxes
        FMuneca                  matlab.ui.control.UIAxes
        AMuneca                  matlab.ui.control.UIAxes
        FCodo                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tiempo; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
           
            %tiempo de duracion del movimiento
            maxim= 4;
            minim= 1;
            duration = minim+(maxim-minim)*rand(1);

        
            % value = app.TiempoTotal.Value - duration;
      [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, duration,'cl');
      [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, duration,'cl');  
      [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, duration,'cl');
      [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, duration,'cl');
      [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'cl');
      [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1, duration,'cl');
      
      
      %HA
      [mSUP, mINF,xR]=  RangosCalidad ( app, [10,14,19,26,31,33,30,26,21,16,10], duration)
%       mSUP = rHAS;
%       mINF = rHAI; % Esto se podria hacer, para que los rangos se guardenen diferentes variables, sin necesidad de crar una funcion para cada movimiento
   
      %MA
      [mSUP, mINF,xR]=  RangosCalidad ( app, [5,6,10,12,15,16,15,12,9,6,5], duration)
      %CF
      [mSUP, mINF,xR]=  RangosCalidad ( app, [67,70,89,108,115,120,115,107,83,72,67], duration)
      %HF
      [mSUP, mINF,xR]=  RangosCalidad ( app, [40,42,45,50,56,60,56,50,45,42,40], duration)
      %MF
      [mSUP, mINF,xR]=  RangosCalidad ( app, [27,25,23,20,21,20,22,20,23,25,27], duration)
      %SC
      [mSUP, mINF,xR]=  RangosCalidad ( app, [25,28,35,42,52,51,54,25,17,10,25], duration)


%  value = app.TiempoTotal.Value - duration;
        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Tiempo;
            closereq ();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.IniciarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ResultadosButton = uibutton(app.GridLayout3, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.FMuneca = uiaxes(app.GridLayout);
            title(app.FMuneca, 'Flexión Muñeca')
            xlabel(app.FMuneca, 'X')
            ylabel(app.FMuneca, 'Y')
            zlabel(app.FMuneca, 'Z')
            app.AMuneca = uiaxes(app.GridLayout);
            title(app.AMuneca, {'Abducción Muñeca'; ''})
            xlabel(app.AMuneca, 'X')
            ylabel(app.AMuneca, 'Y')
            zlabel(app.AMuneca, 'Z')
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasCalidad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end